classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UseParallelCheckBox             matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        CustomDirectoriesPanel          matlab.ui.container.Panel
        DictTable                       matlab.ui.control.Table
        RemoveDirectoryButton           matlab.ui.control.Button
        AddDirectoryButton              matlab.ui.control.Button
        ToggleButton                    matlab.ui.control.Button
        ContinueFromCheckBox            matlab.ui.control.CheckBox
        SetConfigurationsLabel          matlab.ui.control.Label
        SetDataDirectoriesLabel         matlab.ui.control.Label
        UsecustomspikecuttingCheckBox   matlab.ui.control.CheckBox
        TimeStampsDirEditField          matlab.ui.control.EditField
        TimeStampsDirEditFieldLabel     matlab.ui.control.Label
        BrowseTimestampButton           matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        BrowseOutputButton              matlab.ui.control.Button
        OutputDirEditField              matlab.ui.control.EditField
        OutputDirEditFieldLabel         matlab.ui.control.Label
        BrowseRecordingButton           matlab.ui.control.Button
        RecordingDirEditField           matlab.ui.control.EditField
        RecordingDirEditFieldLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        SIMULATEDEditField              matlab.ui.control.EditField
        SIMULATEDEditFieldLabel         matlab.ui.control.Label
        NUM_OF_UNITSEditField           matlab.ui.control.EditField
        NUM_OF_UNITSEditFieldLabel      matlab.ui.control.Label
        UPDATE_GRADESEditField          matlab.ui.control.EditField
        UPDATE_GRADESEditFieldLabel     matlab.ui.control.Label
        ONLY_NEURONSEditField           matlab.ui.control.EditField
        ONLY_NEURONSEditFieldLabel      matlab.ui.control.Label
        ONLY_OVERLAP_WITH_NEURONSEditField  matlab.ui.control.EditField
        ONLY_OVERLAP_WITH_NEURONSEditFieldLabel  matlab.ui.control.Label
        OVERLAPEditField                matlab.ui.control.EditField
        OVERLAPEditFieldLabel           matlab.ui.control.Label
        UPDATE_CLASSIFICATIONEditField  matlab.ui.control.EditField
        UPDATE_CLASSIFICATIONEditFieldLabel  matlab.ui.control.Label
        DEBUGEditField                  matlab.ui.control.EditField
        DEBUGEditFieldLabel             matlab.ui.control.Label
        MAX_EUC_DISTEditField           matlab.ui.control.EditField
        MAX_EUC_DISTEditFieldLabel      matlab.ui.control.Label
        ConfigFilePathEditField         matlab.ui.control.EditField
        ConfigFilePathEditFieldLabel    matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)

        % User spikesort_config.m path
        OriginalConfigFile char

        % Read/write to path
        ConfigFile char
        Config struct

        RecordingsDir char
        TimestampsDir char
        OutputDir char

        DictList cell = {};

        % List of parameters 
        % Need to add further ones below

        KeepList = {
            'DEBUG',
            'MAX_EUC_DIST',
            'UPDATE_CLASSIFICATION',
            'OVERLAP',
            'ONLY_OVERLAP_WITH_NEURONS',
            'ONLY_NEURONS',
            'UPDATE_GRADES',
            'NUM_OF_UNITS',
            'SIMULATED',

            };


        % Type classification for checks (work in progress)
        BoolList = {
            'DEBUG',
            'UPDATE_CLASSIFICATION',
            'ONLY_OVERLAP_WITH_NEURONS',
            'ONLY_NEURONS',
            'UPDATE_GRADES',
            'SIMULATED'
            };
        IntList  = {'NUM_OF_UNITS'};
        NumList  = {'MAX_EUC_DIST', 'OVERLAP'};
        StrList  = {};


    end

    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Note - might be different (based on github)
            % addpath(genpath(fullfile(pwd, '../../clustering_neuron_spikes_with_deep_learning/')));
            addpath(genpath(pwd));

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.m;*.mat','Config Files (*.m, *.mat)'}, ...
                'Select spikesort_config file');

            if isequal(file,0), return; end

            app.ConfigFile = fullfile(path, file);
            app.ConfigFilePathEditField.Value = app.ConfigFile;

            [~, ~, ext] = fileparts(file);


            % Load struct
            switch lower(ext)
                case '.mat'
                    data = load(app.ConfigFile);
                    if isfield(data,'config') && isstruct(data.config)
                        cfg = data.config;
                    else
                        f = fieldnames(data);
                        cfg = data.(f{1});
                    end

                case '.m'
                    cfgFolder = fileparts(app.ConfigFile);
                    addpath(cfgFolder);
                    cfg = spikesort_config();
                    rmpath(cfgFolder);

                otherwise
                    uialert(app.UIFigure, 'Unsupported file type','Error');
                    return;
            end

            % Store struct
            app.Config = cfg;

            app.OutputDir = app.Config.BLIND_PASS_DIR_PRECOMPUTED;
            app.OutputDirEditField.Value = app.Config.BLIND_PASS_DIR_PRECOMPUTED;

            % Fill in fields from KeepList
            for i = 1:numel(app.KeepList)
                fn   = app.KeepList{i};  
                comp = fn + "EditField";         
                if ~isprop(app, comp) || ~isfield(cfg, fn)
                    continue;
                end

                val = cfg.(fn);
                % Convert to string 
                if isstring(val) || ischar(val)
                    s = string(val);
                elseif islogical(val)
                    s = string(val);
                elseif isnumeric(val)
                    s = string(val);
                else
                    s = string(val);  
                end

                app.(comp).Value = s;
            end

            uialert(app.UIFigure, 'Configuration loaded.', 'Success');

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % Check for loaded config
            if isempty(app.Config) || ~isstruct(app.Config)
                uialert(app.UIFigure, 'No config loaded to save changes to.', 'Error');
                return;
            end

            % Update config fields using UI
            for i = 1:numel(app.KeepList)
                fn = app.KeepList{i};
                comp = fn + "EditField";
                if ~isprop(app, comp)
                    continue;
                end

                % User input
                newVal = app.(comp).Value;  

                
                if newVal == ""
                    continue;
                end

                % Convert string back to data type
                if ismember(fn, app.BoolList)
                    % Boolean
                    if strcmpi(newVal, "true")
                        val = true;
                    elseif strcmpi(newVal, "false")
                        val = false;
                    else
                        uialert(app.UIFigure, sprintf('"%s" must be true or false.', fn), 'Validation Error');
                        return;
                    end

                elseif ismember(fn, app.IntList)
                    % Int
                    v = str2double(newVal);
                    if isnan(v) || v~=floor(v)
                        uialert(app.UIFigure, sprintf('"%s" must be an integer.', fn), 'Validation Error');
                        return;
                    end
                    val = v;

                elseif ismember(fn, app.NumList)
                    % Float
                    v = str2double(newVal);
                    if isnan(v)
                        uialert(app.UIFigure, sprintf('"%s" must be numeric.', fn), 'Validation Error');
                        return;
                    end
                    val = v;

                else
                    val = char(newVal);
                end

                % d) Store in config struct
                app.Config.(fn) = val;
            end

            % Prompt user where to save the file
            [file, folder] = uiputfile('*.mat', 'Save configuration as...');
            if isequal(file,0)
                return;
            end
            savePath = fullfile(folder, file);

            % Save struct
            config = app.Config;
            try
                save(savePath, 'config');
                uialert(app.UIFigure, 'Configuration saved to .mat file.', 'Success');
            catch ME
                uialert(app.UIFigure, "Failed to save: " + ME.message, 'Error');
            end

        end

        % Button pushed function: BrowseRecordingButton
        function BrowseRecordingButtonPushed(app, event)
            directory = uigetdir('.', 'Select Recordings Folder');
            if isequal(directory,0), return; end

            app.RecordingsDir = directory;
            app.RecordingDirEditField.Value = directory;

            % Check if directory is empty
            d = dir(fullfile(directory, '*.*'));
            if isempty(d) || numel(d) <= 2
                options = uiconfirm(app.UIFigure, ...
                    'The recordings folder appears to be empty. Do you want to continue?', ...
                    'Empty Folder Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Button pushed function: BrowseOutputButton
        function BrowseOutputButtonPushed(app, event)
            directory = uigetdir('.', 'Select Output Folder');
            if isequal(directory,0), return; end

            app.OutputDir = directory;
            app.OutputDirEditField.Value = directory;

            % Check if outputs folder is not empty
            if ~isempty(dir(fullfile(app.OutputDir, '*.*'))) && numel(dir(fullfile(app.OutputDir, '*.*'))) > 2
                options = uiconfirm(app.UIFigure, ...
                    'The output folder is not empty. Existing files may be overwritten. Do you want to continue?', ...
                    'Overwrite Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if isempty(app.TimestampsDir) || ~isfolder(app.TimestampsDir)
                uialert(app.UIFigure, 'Please select a valid timestamps directory.', 'Error');
                return;
            end

            if isempty(app.RecordingsDir) || ~isfolder(app.RecordingsDir)
                uialert(app.UIFigure, 'Please select a valid recordings directory.', 'Error');
                return;
            end

            if isempty(app.OutputDir) || ~isfolder(app.OutputDir)
                uialert(app.UIFigure, 'Please select a valid output directory.', 'Error');
                return;
            end

            % (so that FP_TO_STATUS_FILE etc. are up to date)
            % app.SaveButtonPushed();  

            app.Config.STATUS_WINDOW = app.StatusTextArea;

            app.Config.DIR_WITH_OG_CHANNEL_RECORDINGS = app.RecordingsDir;

            app.Config.TIMESTAMP_FP = fullfile(app.TimestampsDir,"timestamps.mat");

            app.Config.BLIND_PASS_DIR_PRECOMPUTED = app.OutputDir;

            if app.UsecustomspikecuttingCheckBox.Value
                directory_list = app.DictTable.Data(:, 2);
                app.Config.CUSTOM_SPIKE_CUTTING = true;
                app.Config.CUSTOM_DICTIONARIES_DIRECTORY_LIST = directory_list;
            else
                app.Config.CUSTOM_SPIKE_CUTTING = false;
            end

            % ── 1) If “Continue from status file” is checked, get/create that file
            if app.ContinueFromCheckBox.Value
                % Ask user for existing status log
                [file, path] = uigetfile('*.txt', 'Select Status Log File');
                if isequal(file,0)
                    % Cancelled, fallback: generate new one
                    timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                    fname = "joblog_" + timestamp + ".txt";
                    % path = uigetdir;
                    fullLogPath = fullfile(fname);
                else
                    fullLogPath = fullfile(path, file);
                end
                app.Config.FP_TO_STATUS_FILE = fullLogPath;
        
                % Parse the log file to get what_is_pre_computed
                if isfile(fullLogPath)
                    lines = readlines(fullLogPath);
                    app.Config.what_is_pre_computed = string(lines); % used in algorithm
                else
                    app.Config.what_is_pre_computed = [];
                end
            else
                % Not continuing — create new status log
                timestamp = datestr(now, 'yyyymmdd_HHMMSS');
                fname = "joblog_" + timestamp + ".txt";
                % path = uigetdir;
                % fullLogPath = fullfile(path, fname);
                fullLogPath = fullfile(fname);
                app.Config.FP_TO_STATUS_FILE = fullLogPath;
                app.Config.what_is_pre_computed = [];
            end

            % Check timestamps and recordings for same length

            % Check timestamps.mat
            tsFile = fullfile(app.TimestampsDir, 'timestamps.mat');
            if ~isfile(tsFile)
                uialert(app.UIFigure, 'timestamps.mat not found in the selected folder.', 'Error');
                return;
            end
            tsData = load(tsFile);
            tsFields = fieldnames(tsData);
            timestamps = tsData.(tsFields{1}); 
            szTs = size(timestamps);
            if numel(szTs)~=2 || szTs(1)~=1
                uialert(app.UIFigure, 'timestamps must be a 1×N array.', 'Error');
                return;
            end
            N = szTs(2);
            
            % Check each channel file 
            chanFiles = dir(fullfile(app.RecordingsDir, 'c*.mat'));
            if isempty(chanFiles)
                uialert(app.UIFigure, 'No channel files (c*.mat) found in recordings folder.', 'Error');
                return;
            end
            
            for k = 1:numel(chanFiles)
                fp = fullfile(chanFiles(k).folder, chanFiles(k).name);
                C = load(fp);
                f = fieldnames(C);
                chanData = C.(f{1});   
                szC = size(chanData);
                if numel(szC)~=2 || szC(1)~=1 || szC(2)~=N
                    uialert(app.UIFigure, sprintf( ...
                        'Channel file "%s" must be 1×%d but is %dx%d.', ...
                        chanFiles(k).name, N, szC(1), szC(2)), 'Error');
                    return;
                end
            end
            
            % All lengths match then --> No need to show alert which is
            % below
            % uialert(app.UIFigure, sprintf('Timestamps and %d channel files all match length %d.', ...
            %     numel(chanFiles), N),'Info');


            app.StatusTextArea.Value = "Beginning the analysis.";
            drawnow;


            close all;
            clc;

            % % Fix getting from config
            % zScores   = [3 4 5 6 7 8 9];

            % % Parameter in app need to do - is data simulated or not (ground truth)
            % gtDir     = 'D:\spike_gen_data\Recording By Channel Ground Truth';


            % Run algorithm for each z-score
            % Fix getting values from config

            % for j=1:length(zScores)
            %     close all;
            %     clc;
            %     scale_factor = -1;
            %     dir_with_channel_recordings = app.RecordingsDir;
            %     min_z_score = zScores(j);
            %     min_threshold = 20;
            %     num_dps = 60;
            %     timestamps_dir = app.TimestampsDir;
            %     precomputed_dir_current = app.OutputDir;
            % 
            % 
            %     what_is_precomputed = [""];
            %     % what_is_precomputed = ["mean_and_std","z_score","dictionaries min_z_score "+string(min_z_score)+" num_dps 60","spike_windows min_z_score "+string(min_z_score)+" num dps 60","spikes_per_channel min_z_score " + string(min_z_score)];
            % 
            %     if ~app.UsecustomspikecuttingCheckBox.Value
            %         what_is_precomputed = run_entire_clustering_algorithm_ver_2(scale_factor,dir_with_channel_recordings,min_z_score,num_dps,timestamps_dir,precomputed_dir_current,what_is_precomputed,min_threshold, app.Config);
            %         % what_is_precomputed = ["z_score","mean_and_std"];
            %     else
            %         directory_list = app.DictTable.Data(:, 2);
            %         dictionaries_dir = directory_list(j);
            %         what_is_precomputed = custom_spike_algorithm_run_entire_clustering_algorithm_ver_2(scale_factor,dir_with_channel_recordings,min_z_score,num_dps,timestamps_dir,precomputed_dir_current,what_is_precomputed,min_threshold, dictionaries_dir);
            %     end
            % end

            % Step 4
            [blind_pass_table,fp_to_blind_pass_table] = run_entire_clustering_algorithm_ver_2(app.Config);


            % Step 5: select neurons from blind pass
            % blind_pass_table_only_neurons = blind_pass_table(blind_pass_table{:,"is_neuron"},:);
            blind_pass_table_only_neurons = blind_pass_table(blind_pass_table.is_neuron == 1, :);

            % Step 6: get accuracy prediction categories using grades + universal rank neural network 
            blind_pass_table_only_neurons = add_accuracy_cat_pred_from_nn(blind_pass_table_only_neurons,app.Config);

            % Step 7: get accuracy prediction categories using mean waveform neural network
            blind_pass_table_only_neurons = add_mean_waveform_pred_col(blind_pass_table_only_neurons,app.Config);

            % Step 8: Get Letter Grade
            blind_pass_table_only_neurons = add_letter_grade_based_on_nn(blind_pass_table_only_neurons);

            % Step 8: Use Accuracy Prediction Neural Network to filter out any MUA clusters that made it past the first filter
            bp_table_only_neur_filtered = blind_pass_table_only_neurons(blind_pass_table_only_neurons{:,"grades_pred"}>0,:);

            % Step 9: Merge neurons into groups that represent the same underlying unit
            clusters_organized_by_same_group = determine_which_blind_pass_neurons_overlap(bp_table_only_neur_filtered,app.Config);

            % Step 10: Save Results of merging
            clusters_organized_by_same_group_with_filter_fp = fullfile(app.Config.BLIND_PASS_DIR_PRECOMPUTED,"blind_pass_table_organized_into_same_groups_with_filter");
            create_a_file_if_it_doesnt_exist_and_ret_abs_path(clusters_organized_by_same_group_with_filter_fp);
            save(fullfile(clusters_organized_by_same_group_with_filter_fp,"clusters_organized_by_same_group.mat"),"clusters_organized_by_same_group");

            % Step 11: Merge Neurons into groups without step (for testing purposes)
            clusters_organized_by_same_group_without_filter = determine_which_blind_pass_neurons_overlap(blind_pass_table_only_neurons, app.Config);

            % Step 12: Save the results of step 11
            clusters_organized_by_same_group_without_filter_fp = fullfile(app.Config.BLIND_PASS_DIR_PRECOMPUTED,"blind_pass_table_organized_into_same_groups_without_filter");
            create_a_file_if_it_doesnt_exist_and_ret_abs_path(clusters_organized_by_same_group_without_filter_fp);
            save(fullfile(clusters_organized_by_same_group_without_filter_fp,"clusters_organized_by_same_group_without_filter.mat"),"clusters_organized_by_same_group_without_filter");

            % Step 13: Sort the Results of step 9
            sorted_cluster_groups = use_choose_better_to_sort_groups(clusters_organized_by_same_group,app.Config);

            % Step 13a: Save the sorted groups cell array
            fp_to_sorted_groups = fullfile(app.Config.BLIND_PASS_DIR_PRECOMPUTED,"Sorted Only Neurons Filtered");
            create_a_file_if_it_doesnt_exist_and_ret_abs_path(fp_to_sorted_groups);
            save(fullfile(fp_to_sorted_groups,"sorted_cluster_groups.mat"),"sorted_cluster_groups");

            % Step 14: Sort the results of step 11
            sorted_cluster_groups_no_filter = use_choose_better_to_sort_groups(clusters_organized_by_same_group_without_filter,app.Config);
        
            % Step 14a: Save the results of step 14
            clusters_organized_by_same_group_no_filter_fp = fullfile(app.Config.BLIND_PASS_DIR_PRECOMPUTED,"Sorted Only Neurons No Filter");
            create_a_file_if_it_doesnt_exist_and_ret_abs_path(clusters_organized_by_same_group_no_filter_fp);
            save(fullfile(clusters_organized_by_same_group_no_filter_fp,"sorted_cluster_groups_no_filter.mat"),"sorted_cluster_groups_no_filter");   

        end

        % Button pushed function: BrowseTimestampButton
        function BrowseTimestampButtonPushed(app, event)
            directory = uigetdir('.', 'Select Timestamps Folder');
            if isequal(directory,0), return; end

            app.TimestampsDir = directory;
            app.TimeStampsDirEditField.Value = directory;

            % Check if directory is empty
            if isempty(dir(fullfile(app.TimestampsDir, '*.*'))) || numel(dir(fullfile(app.TimestampsDir, '*.*'))) <= 2
                options = uiconfirm(app.UIFigure, ...
                    'The timestamps folder appears to be empty. Do you want to continue?', ...
                    'Empty Folder Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Value changed function: UsecustomspikecuttingCheckBox
        function UsecustomspikecuttingCheckBoxValueChanged(app, event)
            value = app.UsecustomspikecuttingCheckBox.Value;

            % Call another app that opens in background

            if value
                app.DictTable.Visible = "on";
                app.AddDirectoryButton.Visible = "on";
                app.RemoveDirectoryButton.Visible = "on";
            else
                app.DictTable.Visible = "off";
                app.AddDirectoryButton.Visible = "off";
                app.RemoveDirectoryButton.Visible = "off";
            end

        end

        % Button pushed function: AddDirectoryButton
        function AddDirectoryButtonPushed(app, event)

            % 1) User picks one folder
            folder = uigetdir('.', 'Select a dictionary folder');
            if isequal(folder, 0), return; end
        
            [parent, base] = fileparts(folder);
        
            % 2) Pattern for folder names (floats allowed)
            pat = '^dictionaries min_z_score [0-9]+(?:\.[0-9]+)?\s+num_dps\s+[0-9]+$';
            if isempty(regexp(base, pat, 'once'))
                uialert(app.UIFigure, 'Folder must be "dictionaries min_z_score <num> num_dps <int>".', 'Invalid Folder');
                return;
            end
        
            % 3) Find similar sibling folders
            d      = dir(parent);
            dirs   = d([d.isdir] & ~ismember({d.name},{'.','..'}));
            names  = {dirs.name};
            similar = {};
            for k = 1:numel(names)
                nm = names{k};
                if strcmp(nm, base), continue; end
                if ~isempty(regexp(nm, pat, 'once'))
                    fp = fullfile(parent, nm);
                    if ~any(strcmp(app.DictList, fp))
                        similar{end+1} = fp;  %#ok<AGROW>
                    end
                end
            end
        
            % 4) Always include the one they picked
            toConsider = {folder};
            % If extras exist, ask about adding them
            if ~isempty(similar)
                listStr = strjoin(similar, newline);
                choice = uiconfirm(app.UIFigure, ...
                    sprintf("Found %d additional folders:\n%s\n\nAdd them too?", ...
                            numel(similar), listStr), ...
                    'Add Similar Folders?', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
                if strcmp(choice,'Yes')
                    toConsider = [toConsider, similar];
                end
            end
        
            % 5) Validate each candidate before appending
            validFolders = {};
            for k = 1:numel(toConsider)
                fp = toConsider{k};
        
                try
                    % — load and check sorted_spike_windows_*.mat —
                    swFile = dir(fullfile(fp,'t1 sorted_spike_windows*.mat'));
                    assert(~isempty(swFile), 'Missing sorted_spike_windows file.');
                    S = load(fullfile(swFile(1).folder,swFile(1).name));
                    fn = fieldnames(S); swMap = S.(fn{1});
                    data = swMap('t1'); sz = size(data);
                    assert(numel(sz)>=2 && sz(2)==4, 'sorted_spike_windows size mismatch.');
                    numSpikes = sz(1);
        
                    % — load and check timing_tetrode_dictionary_*.mat —
                    ttFile = dir(fullfile(fp,'t1 timing_tetrode_dictionary*.mat'));
                    assert(~isempty(ttFile), 'Missing timing_tetrode_dictionary file.');
                    T = load(fullfile(ttFile(1).folder,ttFile(1).name));
                    fn = fieldnames(T); ttMap = T.(fn{1});
                    data2 = ttMap('t1'); sz2 = size(data2);
                    assert(numel(sz2)>=2 && sz2(1)==numSpikes, 'Timing dictionary spikes mismatch.');
                    numDpts = sz2(2);
        
                    % — load and check spiking_channel_tetrode_dictionary_*.mat —
                    scFile = dir(fullfile(fp,'t1 spiking_channel_tetrode_dictionary*.mat'));
                    assert(~isempty(scFile), 'Missing spiking_channel_tetrode_dictionary file.');
                    C = load(fullfile(scFile(1).folder,scFile(1).name));
                    fn = fieldnames(C); scMap = C.(fn{1});
                    data3 = scMap('t1'); sz3 = size(data3);
                    assert(isequal(sz3, [1 numSpikes]), 'Spiking channel size mismatch.');
        
                    % — load and check spike_tetrode_dictionary_*.mat —
                    sdFile = dir(fullfile(fp,'t1 spike_tetrode_dictionary*.mat'));
                    assert(~isempty(sdFile), 'Missing spike_tetrode_dictionary file.');
                    D = load(fullfile(sdFile(1).folder,sdFile(1).name));
                    fn = fieldnames(D); sdMap = D.(fn{1});
                    data4 = sdMap('t1'); sz4 = size(data4);
                    assert(isequal(sz4, [numDpts 4 numSpikes]), 'Spike dictionary size mismatch.');
        
                    % — load and check spike_tetrode_dictionaries_samples_format_*.mat —
                    sfFile = dir(fullfile(fp,'t1 spike_tetrode_dictionary_samples_format*.mat'));
                    assert(~isempty(sfFile), 'Missing samples_format file.');
                    F = load(fullfile(sfFile(1).folder,sfFile(1).name));
                    fn = fieldnames(F); sfMap = F.(fn{1});
                    data5 = sfMap('t1'); sz5 = size(data5);
                    assert(isequal(sz5, [numDpts 4 numSpikes]), 'Samples-format size mismatch.');
        
                    % All checks passed
                    validFolders{end+1} = fp;
        
                catch ME
                    uialert(app.UIFigure, sprintf('Folder "%s" failed: %s', fp, ME.message), ...
                           'Validation Error');
                end
            end
        
            % 6) Append only validated folders
            for k = 1:numel(validFolders)
                app.DictList{end+1,1} = validFolders{k};
            end
        
            % 7) Rebuild the UITable
            n = numel(app.DictList);
            data = cell(n,2);
            for i = 1:n
                data{i,1} = i;
                data{i,2} = app.DictList{i};
            end
            app.DictTable.Data = data;


        end

        % Button pushed function: RemoveDirectoryButton
        function RemoveDirectoryButtonPushed(app, event)
            % Get rows that are selected
            sel = app.DictTable.Selection;
            if isempty(sel)
                uialert(app.UIFigure, 'No rows selected to remove.','Info');
                return;
            end
            rows = unique(sel(:,1));
        
            % Remove from DictList from the bottom
            for r = flip(rows')
                app.DictList(r) = [];
            end
        
            % Populate table again
            n = numel(app.DictList);
            data = cell(n,2);
            for i = 1:n
                data{i,1} = i;
                data{i,2} = app.DictList{i};
            end
            app.DictTable.Data = data;
        end

        % Button pushed function: ToggleButton
        function ToggleButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ConfigFilePathEditFieldLabel.Text = 'ConfigFilePath';
            app.ConfigFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.MAX_EUC_DISTEditFieldLabel = uilabel(app.UIFigure);
            app.MAX_EUC_DISTEditFieldLabel.Text = 'MAX_EUC_DIST';
            app.MAX_EUC_DISTEditField = uieditfield(app.UIFigure, 'text');
            app.DEBUGEditFieldLabel = uilabel(app.UIFigure);
            app.DEBUGEditFieldLabel.Text = 'DEBUG';
            app.DEBUGEditField = uieditfield(app.UIFigure, 'text');
            app.DEBUGEditField.Tooltip = {''};
            app.UPDATE_CLASSIFICATIONEditFieldLabel = uilabel(app.UIFigure);
            app.UPDATE_CLASSIFICATIONEditFieldLabel.Text = 'UPDATE_CLASSIFICATION';
            app.UPDATE_CLASSIFICATIONEditField = uieditfield(app.UIFigure, 'text');
            app.OVERLAPEditFieldLabel = uilabel(app.UIFigure);
            app.OVERLAPEditFieldLabel.Text = 'OVERLAP';
            app.OVERLAPEditField = uieditfield(app.UIFigure, 'text');
            app.ONLY_OVERLAP_WITH_NEURONSEditFieldLabel = uilabel(app.UIFigure);
            app.ONLY_OVERLAP_WITH_NEURONSEditFieldLabel.Text = 'ONLY_OVERLAP_WITH_NEURONS';
            app.ONLY_OVERLAP_WITH_NEURONSEditField = uieditfield(app.UIFigure, 'text');
            app.ONLY_NEURONSEditFieldLabel = uilabel(app.UIFigure);
            app.ONLY_NEURONSEditFieldLabel.Text = 'ONLY_NEURONS';
            app.ONLY_NEURONSEditField = uieditfield(app.UIFigure, 'text');
            app.UPDATE_GRADESEditFieldLabel = uilabel(app.UIFigure);
            app.UPDATE_GRADESEditFieldLabel.Text = 'UPDATE_GRADES';
            app.UPDATE_GRADESEditField = uieditfield(app.UIFigure, 'text');
            app.NUM_OF_UNITSEditFieldLabel = uilabel(app.UIFigure);
            app.NUM_OF_UNITSEditFieldLabel.Text = 'NUM_OF_UNITS';
            app.NUM_OF_UNITSEditField = uieditfield(app.UIFigure, 'text');
            app.SIMULATEDEditFieldLabel = uilabel(app.UIFigure);
            app.SIMULATEDEditFieldLabel.Text = 'SIMULATED';
            app.SIMULATEDEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'BrowseButton';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'SaveButton';
            app.RecordingDirEditFieldLabel = uilabel(app.UIFigure);
            app.RecordingDirEditFieldLabel.Text = 'RecordingDir';
            app.RecordingDirEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseRecordingButton = uibutton(app.UIFigure, 'push');
            app.BrowseRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRecordingButtonPushed, true);
            app.BrowseRecordingButton.Text = 'BrowseRecording';
            app.OutputDirEditFieldLabel = uilabel(app.UIFigure);
            app.OutputDirEditFieldLabel.Text = 'OutputDir';
            app.OutputDirEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseOutputButton = uibutton(app.UIFigure, 'push');
            app.BrowseOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutputButton.Text = 'BrowseOutput';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.BrowseTimestampButton = uibutton(app.UIFigure, 'push');
            app.BrowseTimestampButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTimestampButtonPushed, true);
            app.BrowseTimestampButton.Text = 'BrowseTimestamp';
            app.TimeStampsDirEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStampsDirEditFieldLabel.Text = 'TimeStampsDir';
            app.TimeStampsDirEditField = uieditfield(app.UIFigure, 'text');
            app.UsecustomspikecuttingCheckBox = uicheckbox(app.UIFigure);
            app.UsecustomspikecuttingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsecustomspikecuttingCheckBoxValueChanged, true);
            app.UsecustomspikecuttingCheckBox.Text = 'Use custom spike cutting';
            app.SetDataDirectoriesLabel = uilabel(app.UIFigure);
            app.SetDataDirectoriesLabel.Text = 'Set Data Directories';
            app.SetConfigurationsLabel = uilabel(app.UIFigure);
            app.SetConfigurationsLabel.Text = 'Set Configurations';
            app.ContinueFromCheckBox = uicheckbox(app.UIFigure);
            app.ContinueFromCheckBox.Tooltip = {'Check this box if you want to continue from a previous job that encountered an error.'};
            app.ContinueFromCheckBox.Text = 'Continue From';
            app.ToggleButton = uibutton(app.UIFigure, 'push');
            app.ToggleButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleButtonPushed, true);
            app.ToggleButton.Text = 'ToggleButton';
            app.CustomDirectoriesPanel = uipanel(app.UIFigure);
            app.CustomDirectoriesPanel.Title = 'Custom Directories';
            app.AddDirectoryButton = uibutton(app.CustomDirectoriesPanel, 'push');
            app.AddDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectoryButtonPushed, true);
            app.AddDirectoryButton.Visible = 'off';
            app.AddDirectoryButton.Text = 'Add Directory';
            app.RemoveDirectoryButton = uibutton(app.CustomDirectoriesPanel, 'push');
            app.RemoveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDirectoryButtonPushed, true);
            app.RemoveDirectoryButton.Visible = 'off';
            app.RemoveDirectoryButton.Text = 'Remove Directory';
            app.DictTable = uitable(app.CustomDirectoriesPanel);
            app.DictTable.ColumnName = {'Number'; 'Directory of Dictionaries'};
            app.DictTable.RowName = {};
            app.DictTable.Visible = 'off';
            app.UseGPUCheckBox = uicheckbox(app.UIFigure);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.UseParallelCheckBox = uicheckbox(app.UIFigure);
            app.UseParallelCheckBox.Text = 'Use Parallel ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end