classdef pipeline_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Set_Blind_Pass_DirectoryTab     matlab.ui.container.Tab
        RawDaraDirectoryEditField       matlab.ui.control.EditField
        RawDaraDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectRawDataDirectoryButton    matlab.ui.control.Button
        DirectoryYouChoseEditField      matlab.ui.control.EditField
        DirectoryYouChoseEditFieldLabel  matlab.ui.control.Label
        ChooseBlindPassDirectoryButton  matlab.ui.control.Button
        View_Blind_Pass_tableTab        matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        cluster_groups                  matlab.ui.container.Tab
        tagged_unit_stats               matlab.ui.control.Table
        untagged_unit_stats             matlab.ui.control.Table
        UIAxes2_8                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        blind_pass_table = [];
        ground_truth_cell_array = {};
        grouped_clusters = {};
        grouped_cluster_statistics_unfiltered = [];
        grouped_cluster_statistics_filtered = [];
        cluster_groups_untagged = [];
        cluster_groups_with_tags = [];
        config  = [];
        output_dir = "";
        output_dir_selected = false;
        raw_data_dir = "";
        raw_data_dir_selected = false;

    end
    
    methods (Access = private)
        
   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentFilePath = mfilename('fullpath');
            disp(currentFilePath);
            [dir,~,~] = fileparts(currentFilePath);
            cd(dir);
            home_dir = cd("..");
            addpath(genpath(pwd));
            cd(home_dir);
            app.config = spikesort_config();
        end

        % Button pushed function: ChooseBlindPassDirectoryButton
        function ChooseBlindPassDirectoryButtonPushed(app, event)
            try
                app.blind_pass_table = [];
                sel_path = uigetdir();
                app.output_dir = "";
                app.output_dir_selected = false;
                app.config.BLIND_PASS_DIR_PRECOMPUTED = sel_path;
                app.output_dir = sel_path;
                app.output_dir_selected = true;
                app.DirectoryYouChoseEditField.Value = sel_path;
                
            catch ME
                disp("Selection aborted.")
            end
 
           
        end

        % Button down function: View_Blind_Pass_tableTab
        function View_Blind_Pass_tableTabButtonDown(app, event)
            if ~app.output_dir_selected
                throw_error_diaglogue("You haven't set a blind_pass_directory yet. Please do so in the set_blind_pass_directory_tab before viewing.");
                return;
            end
            
            if isempty(app.blind_pass_table)
                [d,fig] = app_progress_bar("Loading BP Table");
                try
                    app.blind_pass_table = importdata(fullfile(app.output_dir,"blind_pass_table","blind_pass_table.mat"));
                catch
                    disp("Couldn't load the blind pass table.")
                    disp("Ensure that")
                    disp(app.output_dir);
                    disp("Has a directory 'blind_pass_table' and that inside 'blind_pass_table' there is a file called 'blind_pass_table.mat");
                end
                close(d);
                close(fig);
            end
            update_ui_table(app.UITable,app.blind_pass_table,["Z Score","Tetrode","Cluster","is_neuron","Max_Overlap_perc_With_Unit","Max_Overlap_Unit","accuracy"])
            
        end

        % Button down function: cluster_groups
        function cluster_groupsButtonDown(app, event)
            if ~app.output_dir_selected
                throw_error_diaglogue("You haven't set a blind_pass_directory yet. Please do so in the set_blind_pass_directory_tab before viewing.");
                return;
            end

            [d,fig] = app_progress_bar("Loading Cluster Groups Unfiltered");
            if isempty(app.cluster_groups_untagged)
                try
                    app.cluster_groups_untagged = importdata(fullfile(app.output_dir,"default_cluster_groups.mat"));
                catch
                    disp("Couldn't load the default_cluster_groups.mat")
                    disp("Ensure that")
                    disp(app.output_dir);
                    disp("Has a directory 'blind_pass_table' and that inside 'blind_pass_table' there is a file called 'blind_pass_table.mat");
                end
            end
            close(d);
            close(fig)

            [d,fig] = app_progress_bar("Getting Cluster Groups Unfiltered statistics");
            if isempty(app.grouped_cluster_statistics_unfiltered)
                app.grouped_cluster_statistics_unfiltered =  analyze_grouped_clusters(app.cluster_groups_untagged ,app.ground_truth_cell_array);
                update_ui_table(app.untagged_unit_stats,app.grouped_cluster_statistics_unfiltered)
            end
            close(d);
            close(fig);

            [d,fig] = app_progress_bar("Loading Cluster Groups Filtered Through Tagging");
            if isempty(app.cluster_groups_with_tags)
                try
                    app.cluster_groups_with_tags = importdata(fullfile(app.output_dir,"revised_cluster_groups.mat"));
                catch
                    disp("Couldn't load the revised_cluster_groups.mat.")
                    disp("Ensure that")
                    disp(app.output_dir);
                    disp("Has a file called 'revised_cluster_groups.mat");
                end
            end
            close(d);
            close(fig);

            [d,fig] = app_progress_bar("Getting Cluster Groups filtered statistics");
            if isempty(app.grouped_cluster_statistics_filtered)
                app.grouped_cluster_statistics_filtered =  analyze_grouped_clusters(app.cluster_groups_with_tags,app.ground_truth_cell_array);
                update_ui_table(app.tagged_unit_stats,app.grouped_cluster_statistics_filtered)
            end
            close(d);
            close(fig);
        end

        % Cell selection callback: untagged_unit_stats
        function untagged_unit_statsCellSelection(app, event)
            indices = event.Indices;

            %get the group number
            group_number = app.untagged_unit_stats.Data{indices(1),"Group Number"};


            %find where this same group number appears in the second table
            idx = find(app.tagged_unit_stats.Data{:,"Group Number"}==group_number);

            scroll_to_complimentary_row_in_table(app.tagged_unit_stats,idx)

            %update the untagged plots with the mean waveform
            array_of_un_plots = [app.UIAxes2,app.UIAxes2_2,app.UIAxes2_3,app.UIAxes2_4];
            update_wf_axes(array_of_un_plots,app.cluster_groups_untagged{group_number});

            %update the taged plots with the mean waveform
            array_of_tgged_plots = [app.UIAxes2_5,app.UIAxes2_6,app.UIAxes2_7,app.UIAxes2_8];
            update_wf_axes(array_of_tgged_plots,app.cluster_groups_with_tags{idx});

            
        end

        % Button pushed function: SelectRawDataDirectoryButton
        function SelectRawDataDirectoryButtonPushed(app, event)
            try
                sel_path = uigetdir();
                app.raw_data_dir = sel_path;
                app.raw_data_dir_selected = true;

            catch ME
                disp("Selection aborted.")
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Set_Blind_Pass_DirectoryTab = uitab(app.TabGroup);
            app.Set_Blind_Pass_DirectoryTab.Title = 'Set_Blind_Pass_Directory';
            app.ChooseBlindPassDirectoryButton = uibutton(app.Set_Blind_Pass_DirectoryTab, 'push');
            app.ChooseBlindPassDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseBlindPassDirectoryButtonPushed, true);
            app.ChooseBlindPassDirectoryButton.Text = 'Choose Blind Pass Directory';
            app.DirectoryYouChoseEditFieldLabel = uilabel(app.Set_Blind_Pass_DirectoryTab);
            app.DirectoryYouChoseEditFieldLabel.Enable = 'off';
            app.DirectoryYouChoseEditFieldLabel.Text = 'Directory You Chose';
            app.DirectoryYouChoseEditField = uieditfield(app.Set_Blind_Pass_DirectoryTab, 'text');
            app.DirectoryYouChoseEditField.Editable = 'off';
            app.DirectoryYouChoseEditField.Enable = 'off';
            app.SelectRawDataDirectoryButton = uibutton(app.Set_Blind_Pass_DirectoryTab, 'push');
            app.SelectRawDataDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataDirectoryButtonPushed, true);
            app.SelectRawDataDirectoryButton.Text = 'Select Raw Data Directory';
            app.RawDaraDirectoryEditFieldLabel = uilabel(app.Set_Blind_Pass_DirectoryTab);
            app.RawDaraDirectoryEditFieldLabel.Text = 'Raw Dara Directory';
            app.RawDaraDirectoryEditField = uieditfield(app.Set_Blind_Pass_DirectoryTab, 'text');
            app.View_Blind_Pass_tableTab = uitab(app.TabGroup);
            app.View_Blind_Pass_tableTab.Title = 'View_Blind_Pass_table';
            app.View_Blind_Pass_tableTab.ButtonDownFcn = createCallbackFcn(app, @View_Blind_Pass_tableTabButtonDown, true);
            app.UIAxes = uiaxes(app.View_Blind_Pass_tableTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.View_Blind_Pass_tableTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.cluster_groups = uitab(app.TabGroup);
            app.cluster_groups.Title = 'View Groups';
            app.cluster_groups.ButtonDownFcn = createCallbackFcn(app, @cluster_groupsButtonDown, true);
            app.UIAxes2 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_4 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_5 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_6 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_7 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_8 = uiaxes(app.cluster_groups);
            zlabel(app.UIAxes2_8, 'Z')
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            app.untagged_unit_stats = uitable(app.cluster_groups);
            app.untagged_unit_stats.ColumnName = {'Group_#'; 'Dominant Unit'; 'Max %'; 'Unit Breakdown'};
            app.untagged_unit_stats.RowName = {};
            app.untagged_unit_stats.CellSelectionCallback = createCallbackFcn(app, @untagged_unit_statsCellSelection, true);
            app.tagged_unit_stats = uitable(app.cluster_groups);
            app.tagged_unit_stats.ColumnName = {'Group_#'; 'Dominant Unit'; 'Max %'; 'Unit Breakdown'};
            app.tagged_unit_stats.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pipeline_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end