classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable2                      matlab.ui.control.Table
        AlertsTextArea_2              matlab.ui.control.TextArea
        AlertsTextArea_2Label         matlab.ui.control.Label
        AlertsTextArea                matlab.ui.control.TextArea
        AlertsTextAreaLabel           matlab.ui.control.Label
        ClustersFoundEditField        matlab.ui.control.NumericEditField
        ClustersFoundEditFieldLabel   matlab.ui.control.Label
        UITable_3                     matlab.ui.control.Table
        AfterRemovingMUAPredictions_____________________________Label  matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        AfterMUAornotFilter_____________________________Label  matlab.ui.control.Label
        BlindPassStatistics_____________________________Label  matlab.ui.control.Label
        AddGroupedClustersButton_2    matlab.ui.control.Button
        SelectABlindPassTableButton   matlab.ui.control.Button
        SelectaGroundTruthFileButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        a                             matlab.ui.control.Table
        AfterGrouping_____________________________Label  matlab.ui.control.Label
        UIAxes2_5                     matlab.ui.control.UIAxes
        UIAxes2_4                     matlab.ui.control.UIAxes
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        blind_pass_table = [];
        ground_truth_cell_array = {};
        grouped_clusters = {};
        grouped_cluster_statistics = [];
    end
    
    methods (Access = private)
        
        function [can_analyze] = check_that_parameters_are_set(app)
            can_analyze = false;
            if ~isempty(app.blind_pass_table) && ~isempty(app.ground_truth_cell_array)
                can_analyze = true;
            end
        end
        function [data_to_be_returned] = call_analysis(app,can_analyze)
            data_to_be_returned = [];
            if can_analyze
                data_to_be_returned = analyze_cluster_table(app.blind_pass_table,app.ground_truth_cell_array);
            end
        end
        function [] = update_ui_table(app,table_to_update,data_table)
            if ~isempty(data_table)
                table_to_update.Data = data_table;
                app.ClustersFoundEditField.Value = size(app.blind_pass_table,1);
                table_to_update.ColumnName = string(data_table.Properties.VariableNames);
                only_neurons = app.blind_pass_table(boolean(app.blind_pass_table{:,"is_neuron"}),:);
                analysis_of_first_filter = analyze_cluster_table(only_neurons,app.ground_truth_cell_array);
                [alert_list,change_in_accuracy] = compare_previous_stage_to_new_stage(data_table,analysis_of_first_filter);
                only_mua = app.blind_pass_table(boolean(app.blind_pass_table{:,"is_neuron"}==0),:);
                histogram(app.UIAxes,only_mua{:,"accuracy"},100);
                app.UITable_2.Data = [analyze_cluster_table(only_neurons,app.ground_truth_cell_array),change_in_accuracy];
                app.AlertsTextArea.Value = alert_list;

                only_neurons_with_pred_filter = only_neurons(boolean(only_neurons{:,"grades_pred"}>0),:);
                analysis_of_sec_filter = analyze_cluster_table(only_neurons_with_pred_filter,app.ground_truth_cell_array);
                [alert_list,change_in_accuracy] = compare_previous_stage_to_new_stage(analysis_of_first_filter,analysis_of_sec_filter);
                histogram(app.UIAxes_2,only_neurons{only_neurons{:,"grades_pred"}<1,"accuracy"},100);
                app.UITable_3.Data = [analysis_of_sec_filter,change_in_accuracy];
                app.AlertsTextArea_2.Value = alert_list;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectABlindPassTableButton
        function SelectABlindPassTableButtonPushed(app, event)
            try
                [file_name,file_loc] = uigetfile();
                app.blind_pass_table = importdata(fullfile(file_loc,file_name));
                update_ui_table(app,app.UITable,call_analysis(app,check_that_parameters_are_set(app)));
            catch ME
                disp("Selection aborted.")
                display(ME.message)
                display(ME.cause)
                display(ME.stack)
            end
        end

        % Button pushed function: SelectaGroundTruthFileButton
        function SelectaGroundTruthFileButtonPushed(app, event)
            try
                [file_name,file_loc] = uigetfile();
                app.ground_truth_cell_array = importdata(fullfile(file_loc,file_name));
                update_ui_table(app,app.UITable,call_analysis(app,check_that_parameters_are_set(app)));
            catch
                disp("Selection aborted.")
            end
        end

        % Button pushed function: AddGroupedClustersButton_2
        function AddGroupedClustersButtonPushed(app, event)
            
                [file_name,file_loc] = uigetfile();
                app.grouped_clusters = importdata(fullfile(file_loc,file_name));
                [app.grouped_cluster_statistics,is_in_table]= analyze_grouped_clusters(app.grouped_clusters,app.ground_truth_cell_array);
                
                app.a.Data = app.grouped_cluster_statistics;
                app.UITable2.Data = is_in_table;
                app.UITable2.ColumnName = string(is_in_table.Properties.VariableNames);

        end

        % Cell edit callback: UITable_3, a
        function aSelectionChanged(app, event)
            % selection = app.a.Selection;
            % view_group_breakdown(app.grouped_cluster_statistics,selection,app.UIAxes2);
        end

        % Cell selection callback: a
        function aCellSelection(app, event)
            indices = event.Indices;
            view_group_breakdown(app.grouped_cluster_statistics,indices,app.UIAxes2,[app.UIAxes2_2,app.UIAxes2_3,app.UIAxes2_4,app.UIAxes2_5],app.grouped_clusters{indices(1)});
            %check_cluster_groups_for_consistent_overlap(app.grouped_clusters(indices(1)),spikesort_config());
        end

        % Selection changed function: a
        function aSelectionChanged2(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Distribution of Accuracies of Filtered Data')
            xlabel(app.UIAxes, 'Accuracy')
            ylabel(app.UIAxes, 'Number of Filtered')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Distribution of Accuracies of Filtered Data')
            xlabel(app.UIAxes_2, 'Accuracy')
            ylabel(app.UIAxes_2, 'Number of Filtered')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Unit Break Down')
            xlabel(app.UIAxes2, 'Unit')
            ylabel(app.UIAxes2, 'Unit Count')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Unit Break Down')
            xlabel(app.UIAxes2_2, 'Arbitrary Units')
            ylabel(app.UIAxes2_2, 'Microvolts')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.UIFigure);
            title(app.UIAxes2_3, 'Unit Break Down')
            xlabel(app.UIAxes2_3, 'Arbitrary Units')
            ylabel(app.UIAxes2_3, 'Microvolts')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.UIFigure);
            title(app.UIAxes2_4, 'Unit Break Down')
            xlabel(app.UIAxes2_4, 'Arbitrary Units')
            ylabel(app.UIAxes2_4, 'Microvolts')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_5 = uiaxes(app.UIFigure);
            title(app.UIAxes2_5, 'Unit Break Down')
            xlabel(app.UIAxes2_5, 'Arbitrary Units')
            ylabel(app.UIAxes2_5, 'Microvolts')
            zlabel(app.UIAxes2_5, 'Z')
            app.AfterGrouping_____________________________Label = uilabel(app.UIFigure);
            app.AfterGrouping_____________________________Label.Text = '____________________________________After Grouping_____________________________';
            app.a = uitable(app.UIFigure);
            app.a.ColumnName = {'"Group Number"'; '"Dominant Unit"'; '"Max Percentage"'; '"# Members"'; '"Unit Breakdown"'; '"All Same Unit"'};
            app.a.RowName = {};
            app.a.CellEditCallback = createCallbackFcn(app, @aSelectionChanged, true);
            app.a.CellSelectionCallback = createCallbackFcn(app, @aCellSelection, true);
            app.a.SelectionChangedFcn = createCallbackFcn(app, @aSelectionChanged2, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Unit'; '# of Appearences'; 'Average Accuracy'; 'Min Accuracy'; 'Max Accuracy'};
            app.UITable.RowName = {};
            app.SelectaGroundTruthFileButton = uibutton(app.UIFigure, 'push');
            app.SelectaGroundTruthFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaGroundTruthFileButtonPushed, true);
            app.SelectaGroundTruthFileButton.Text = 'Select a Ground Truth File';
            app.SelectABlindPassTableButton = uibutton(app.UIFigure, 'push');
            app.SelectABlindPassTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectABlindPassTableButtonPushed, true);
            app.SelectABlindPassTableButton.Text = 'Select A Blind Pass Table';
            app.AddGroupedClustersButton_2 = uibutton(app.UIFigure, 'push');
            app.AddGroupedClustersButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddGroupedClustersButtonPushed, true);
            app.AddGroupedClustersButton_2.Text = 'Add Grouped Clusters';
            app.BlindPassStatistics_____________________________Label = uilabel(app.UIFigure);
            app.BlindPassStatistics_____________________________Label.Text = '_____________________________________Blind Pass Statistics _____________________________';
            app.AfterMUAornotFilter_____________________________Label = uilabel(app.UIFigure);
            app.AfterMUAornotFilter_____________________________Label.Text = '____________________________________After MUA or not Filter _____________________________';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Unit'; '# of Appearences'; 'Average Accuracy'; 'Min Accuracy'; 'Max Accuracy'; 'Change in Accuracy'};
            app.UITable_2.RowName = {};
            app.AfterRemovingMUAPredictions_____________________________Label = uilabel(app.UIFigure);
            app.AfterRemovingMUAPredictions_____________________________Label.Text = '____________________________________After Removing MUA Predictions _____________________________';
            app.UITable_3 = uitable(app.UIFigure);
            app.UITable_3.ColumnName = {'Unit'; '# of Appearences'; 'Average Accuracy'; 'Min Accuracy'; 'Max Accuracy'; 'Change in Accuracy'};
            app.UITable_3.RowName = {};
            app.UITable_3.CellEditCallback = createCallbackFcn(app, @aSelectionChanged, true);
            app.ClustersFoundEditFieldLabel = uilabel(app.UIFigure);
            app.ClustersFoundEditFieldLabel.Text = 'Clusters Found';
            app.ClustersFoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClustersFoundEditField.Editable = 'off';
            app.AlertsTextAreaLabel = uilabel(app.UIFigure);
            app.AlertsTextAreaLabel.Text = 'Alerts';
            app.AlertsTextArea = uitextarea(app.UIFigure);
            app.AlertsTextArea_2Label = uilabel(app.UIFigure);
            app.AlertsTextArea_2Label.Text = 'Alerts';
            app.AlertsTextArea_2 = uitextarea(app.UIFigure);
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end